classdef orient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PitchGaugeLabel                 matlab.ui.control.Label
        PitchGauge                      matlab.ui.control.SemicircularGauge
        StartButton                     matlab.ui.control.Button
        OrientationTestingLabel         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        CurrentPitchAngleEditFieldLabel  matlab.ui.control.Label
        CurrentPitchAngleEditField      matlab.ui.control.NumericEditField
        TimeExecutedEditFieldLabel      matlab.ui.control.Label
        TimeExecutedEditField           matlab.ui.control.NumericEditField
        RollGaugeLabel                  matlab.ui.control.Label
        RollGauge                       matlab.ui.control.SemicircularGauge
        YawGaugeLabel                   matlab.ui.control.Label
        YawGauge                        matlab.ui.control.SemicircularGauge
        CurrentRollAngleEditFieldLabel  matlab.ui.control.Label
        CurrentRollAngleEditField       matlab.ui.control.NumericEditField
        CurrentYawAngleEditFieldLabel   matlab.ui.control.Label
        CurrentYawAngleEditField        matlab.ui.control.NumericEditField
        InContactLampLabel              matlab.ui.control.Label
        InContactLamp                   matlab.ui.control.Lamp
    end


    properties (Access = private)
        rosinit;
        maxPitch = 0;
        maxRoll = 0;
        maxYaw = 0;
        minPitch = 0;
        minRoll = 0;
        minYaw = 0;
        myTimer = timer('Period', 0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
        pitch = 0;
        roll = 0;
        stopFlag = false;
        sub = rossubscriber('/iiwa_fk/');
        subforce= rossubscriber('/compensated_forces_ft_frame'); 
        yaw = 0;
        fz =0;
    end

    methods (Access = private)
    
        function changeGaugeReading(app)
            % Pitch Value
            if app.pitch < app.minPitch
                app.minPitch = app.pitch;
            elseif app.pitch > app.maxPitch
                app.maxPitch = app.pitch;
            end
            if app.minPitch ~= app.maxPitch
                app.PitchGauge.ScaleColorLimits = [app.minPitch app.maxPitch];
            end
            app.PitchGauge.Value = app.pitch;
%             % Roll Value
%             if app.roll < app.minRoll
%                 app.minRoll = app.roll;
%             elseif app.roll > app.maxRoll
%                 app.maxRoll = app.roll;
%             end
%             if app.minRoll ~= app.maxRoll
%                 app.RollGauge.ScaleColorLimits = [app.minRoll app.maxRoll];
%             end
%             app.RollGauge.Value = app.roll;
%             % Yaw Value
%             if app.yaw < app.minYaw
%                 app.minYaw = app.yaw;
%             elseif app.yaw > app.maxYaw
%                 app.maxYaw = app.yaw;
%             end
%             if app.minYaw ~= app.maxYaw
%                 app.YawGauge.ScaleColorLimits = [app.minYaw app.maxYaw];
%             end
%             app.YawGauge.Value = app.yaw;
        end
        
        
        function displayTime(app)
             app.TimeExecutedEditField.Value = app.myTimer.TasksExecuted/10;
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            pause on
            % Timer function
            app.myTimer.TimerFcn = @(x,y) app.displayTime;          
            start(app.myTimer);
            % Pitch Gauge Color
            app.PitchGauge.ScaleColors = [0 1 0];
            app.PitchGauge.ScaleColorLimits = [-1e-6 1e-6];
            % Roll Gauge Color
            app.RollGauge.ScaleColors = [0 1 0];
            app.RollGauge.ScaleColorLimits = [-1e-6 1e-6];
            % Yaw Gauge Color
            app.YawGauge.ScaleColors = [0 1 0];
            app.YawGauge.ScaleColorLimits = [-1e-6 1e-6];
            % Conatct Lamp
            app.InContactLamp.Color = [1 0 0];
            % Start execution until reset
            while (~app.stopFlag)
                % Read message
                msg = receive(app.sub);
                msgforce = receive(app.subforce);
                % Get Force
                app.fz= abs(msgforce.Wrench.Force.Z);
                % Set pitch
                app.pitch = msg.Angular.Y*(180/pi);
                app.CurrentPitchAngleEditField.Value = app.pitch;
                if app.pitch > 60
                    app.pitch = 0;
                elseif app.pitch < -60
                    app.pitch = 0;
                end
                % Set roll
                app.roll = msg.Angular.X*(180/pi);
                app.CurrentRollAngleEditField.Value = app.roll;
                app.RollGauge.Value = app.roll;

                
%                 if app.roll < 0
%                     app.roll =app.roll+180;
%                     if app.roll < -60
%                         app.roll = 0;
%                     elseif app.roll > -60
%                         app.roll = 0;
%                     end
%                 elseif app.roll > 0
%                     app.roll =-app.roll+180;
%                     if app.roll < -60
%                         app.roll = 0;
%                     elseif app.roll > -60
%                         app.roll = 0;
%                     end
%                 end
                % Set yaw
                app.yaw = msg.Angular.Z*(180/pi);
                app.CurrentYawAngleEditField.Value = app.yaw;
                app.YawGauge.Value = app.yaw;
%                 app.yaw = app.yaw-180;
%                 if app.yaw < 60
%                     app.yaw = 0;
%                 elseif app.yaw > -60
%                     app.yaw = 0;
%                 end
%                 
                %% Check for Lamp
                if app.fz > 1
                    app.InContactLamp.Color = [0 1 0];
                else
                    app.InContactLamp.Color = [1 0 0];
                end
                % Update the gauges
                changeGaugeReading(app);
%                 pause(0.5);
            end
            % Reset all gauge parameters - pitch
            app.PitchGauge.ScaleColors = [1 1 1];
            app.PitchGauge.Value = 0;
            app.minPitch = 0;
            app.maxPitch = 0;
            app.CurrentPitchAngleEditField.Value = 0;
            % Reset all gauge parameters - roll
            app.RollGauge.ScaleColors = [1 1 1];
            app.minRoll = 0;
            app.maxRoll = 0;
            app.RollGauge.Value = 0;
            app.CurrentRollAngleEditField.Value = 0;
            % Reset all gauge parameters - Yaw
            app.YawGauge.ScaleColors = [1 1 1];
            app.minYaw = 0;
            app.maxYaw = 0;
            app.YawGauge.Value = 0;
            app.CurrentYawAngleEditField.Value = 0;
            % Resetting Lamp
            app.InContactLamp.Color = [1 0 0];
            % Reset stop flag
            app.stopFlag = false;
            % Stop timer
            stop(app.myTimer);
            app.TimeExecutedEditField.Value = 0;
            pause off
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.stopFlag = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PitchGaugeLabel = uilabel(app.UIFigure);
            app.PitchGaugeLabel.Text = 'Pitch';
            app.PitchGauge = uigauge(app.UIFigure, 'semicircular');
            app.PitchGauge.Limits = [-60 60];
            app.PitchGauge.MajorTicks = [-60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OrientationTestingLabel = uilabel(app.UIFigure);
            app.OrientationTestingLabel.Text = 'Orientation Testing';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CurrentPitchAngleEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPitchAngleEditFieldLabel.Text = 'Current Pitch Angle';
            app.CurrentPitchAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeExecutedEditFieldLabel = uilabel(app.UIFigure);
            app.TimeExecutedEditFieldLabel.Text = 'Time Executed';
            app.TimeExecutedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollGaugeLabel = uilabel(app.UIFigure);
            app.RollGaugeLabel.Text = 'Roll';
            app.RollGauge = uigauge(app.UIFigure, 'semicircular');
            app.RollGauge.Limits = [-360 360];
            app.RollGauge.MajorTicks = [-360 -320 -280 -240 -200 -160 -120 -80 -40 0 40 80 120 160 200 240 280 320 360];
            app.YawGaugeLabel = uilabel(app.UIFigure);
            app.YawGaugeLabel.Text = 'Yaw';
            app.YawGauge = uigauge(app.UIFigure, 'semicircular');
            app.YawGauge.Limits = [-360 360];
            app.YawGauge.MajorTicks = [-360 -320 -280 -240 -200 -160 -120 -80 -40 0 40 80 120 160 200 240 280 320 360];
            app.CurrentRollAngleEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentRollAngleEditFieldLabel.Text = 'Current Roll Angle';
            app.CurrentRollAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYawAngleEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYawAngleEditFieldLabel.Text = 'Current Yaw Angle';
            app.CurrentYawAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.InContactLampLabel = uilabel(app.UIFigure);
            app.InContactLampLabel.Text = 'In Contact';
            app.InContactLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = orient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end