classdef pos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PositionTestingLabel        matlab.ui.control.Label
        Position1ReachedLampLabel   matlab.ui.control.Label
        Position1ReachedLamp        matlab.ui.control.Lamp
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        Position2ReachedLampLabel   matlab.ui.control.Label
        Position2ReachedLamp        matlab.ui.control.Lamp
        UIAxes_3                    matlab.ui.control.UIAxes
        Position3ReachedLampLabel   matlab.ui.control.Label
        Position3ReachedLamp        matlab.ui.control.Lamp
        StartButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        TimeExecutedEditFieldLabel  matlab.ui.control.Label
        TimeExecutedEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        rosinit;
        myTimer = timer('Period', 0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
        stopFlag = false;
        sub = rossubscriber('/button/');
    end

    methods (Access = private)
    
        function displayTime(app)
             app.TimeExecutedEditField.Value = app.myTimer.TasksExecuted/10;
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            pause on
            % Timer function
            app.myTimer.TimerFcn = @(x,y) app.displayTime;          
            start(app.myTimer);
            % Display images
            imshow(imread('img1.jpeg'), 'Parent', app.UIAxes);
            imshow(imread('img2.jpeg'), 'Parent', app.UIAxes_2);
            imshow(imread('img3.jpeg'), 'Parent', app.UIAxes_3);
            % Start execution until reset
            while (~app.stopFlag)
                % Read message
                msg = receive(app.sub);
                % Check if Position 1 reached
                if strcmp(msg.Data,'btn90')
                    app.Position1ReachedLamp.Color = [0 1 0];
                end
                % Check if Position 2 reached
                if strcmp(msg.Data,'btn45')
                    app.Position2ReachedLamp.Color = [0 1 0];
                end
                % Check if Position 3 reached
                if strcmp(msg.Data,'btn40')
                    app.Position3ReachedLamp.Color = [0 1 0];
                end
%                 pause(0.5);
            end
            % Reset all lamps
            app.Position1ReachedLamp.Color = [1 0 0];
            app.Position2ReachedLamp.Color = [1 0 0];
            app.Position3ReachedLamp.Color = [1 0 0];
            % Reset stop flag
            app.stopFlag = false;
            % Stop timer
            stop(app.myTimer);
            app.TimeExecutedEditField.Value = 0;
            pause off
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.stopFlag = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PositionTestingLabel = uilabel(app.UIFigure);
            app.PositionTestingLabel.Text = 'Position Testing';
            app.Position1ReachedLampLabel = uilabel(app.UIFigure);
            app.Position1ReachedLampLabel.Text = 'Position 1 Reached';
            app.Position1ReachedLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.Position2ReachedLampLabel = uilabel(app.UIFigure);
            app.Position2ReachedLampLabel.Text = 'Position 2 Reached';
            app.Position2ReachedLamp = uilamp(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.Position3ReachedLampLabel = uilabel(app.UIFigure);
            app.Position3ReachedLampLabel.Text = 'Position 3 Reached';
            app.Position3ReachedLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TimeExecutedEditFieldLabel = uilabel(app.UIFigure);
            app.TimeExecutedEditFieldLabel.Text = 'Time Executed';
            app.TimeExecutedEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = pos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end